classdef EegBatchExtractApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        pushbuttonSelect               matlab.ui.control.Button
        pushbuttonChanlocs             matlab.ui.control.Button
        editImputeFilename             matlab.ui.control.EditField
        pushbuttonBatch                matlab.ui.control.Button
        editSelectPath                 matlab.ui.control.EditField
        editSelectFileNum              matlab.ui.control.EditField
        uibuttongroup                  matlab.ui.container.ButtonGroup
        radiobuttonDFA                 matlab.ui.control.RadioButton
        radiobuttonCoh                 matlab.ui.control.RadioButton
        radiobuttonPSD                 matlab.ui.control.RadioButton
        radiobuttonIAF                 matlab.ui.control.RadioButton
        radiobuttonPower               matlab.ui.control.RadioButton
        radiobuttonFAA                 matlab.ui.control.RadioButton
        radiobuttonTF                  matlab.ui.control.RadioButton
        radiobuttonReview              matlab.ui.control.RadioButton
        radiobuttonMSE                 matlab.ui.control.RadioButton
        popupmenuFreq                  matlab.ui.control.DropDown
        checkboxdB                     matlab.ui.control.CheckBox
        pushbuttonStats                matlab.ui.control.Button
        checkboxInterpolationCleaning  matlab.ui.control.CheckBox
        checkboxRunICAfilter           matlab.ui.control.CheckBox
        editImputePath                 matlab.ui.control.EditField
        checkboxInterpolate            matlab.ui.control.CheckBox
        textMaxMissing                 matlab.ui.control.Label
        editImputeChannels             matlab.ui.control.EditField
        maxmissingSpinnerLabel         matlab.ui.control.Label
        maxmissingSpinner              matlab.ui.control.Spinner
        checkboxImpute                 matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
                        
       function strlist = GetUIControlData(app, hObject)
            % pass a handle to the gui, and it will extract all the UIControl object
            % data (slider, checkbox value and edit strings).
            
            strlist = struct;

            ch = get(hObject,'ch');
            count = 0;
            for c=1:length(ch)
                skip = false;
                tag = get(ch(c), 'tag');
                switch get(ch(c), 'type')
                    case {'uiedit'}
                        val = sprintf('%s',get(ch(c),'string'));
                    case {'uieditfield'}
                        val = sprintf('%s',get(ch(c),'value'));
                    case {'uicheckbox','uislider','uispinner'}
                        val = sprintf('%.4f',get(ch(c),'value'));
                    case {'uidropdown'}
                        % not all versions of matlab keep track of
                        % valueindex.
                        if isprop(ch(c),'valueindex')
                            val = get(ch(c), 'valueindex');
                        else
                            str = get(ch(c), 'value');
                            items = get(ch(c), 'items');
                            val = find(strcmpi(items, str));
                            if isempty(val)
                                val = 0;
                            elseif length(val)>1
                                val = val(1);
                            end
                        end
                        % convert to string
                        val = sprintf('%.4f', val);
                    
                    case {'uibuttongroup'}
                        % get the tag of the selected object.
                        val = get(get(ch(c),'SelectedObject'),'tag');
                        
                    otherwise
                        skip=true;
                end
                if ~skip
                    count = count + 1;
                    strlist(count).key = tag;
                    strlist(count).val = val;
                end
            end
            
            strlist = struct2table(strlist);
        end
        
        function SetUIControlData(app, hObject, strlist)
            % SETUICONTROLDATA fills the data with the values in strlist ------------
            
            % hObject holds the figure with the app struct

            ch = get(hObject,'ch');
            for c=1:length(ch)
                tag = get(ch(c), 'tag');
                ndx = find(strcmpi(strlist.key, tag));
                
                if isempty(ndx)
                    continue
                end
                
                % get the value
                if iscell(strlist.val)
                    val = strlist.val{ndx};
                    if iscell(val)
                        val = val{1};
                    end
                else
                    try
                        val = strlist.val{ndx};
                    catch
                        val = strlist.val(ndx);
                    end
                end
                
                switch get(ch(c), 'type')
                    case {'uiedit'}
                        if length(ndx)==1 %#ok<ISCL>
                            if isnumeric(val)
                                set(ch(c),'string', sprintf('%.4f', val));
                            else
                                set(ch(c),'string', sprintf('%s', val));
                            end
                        end
                        pause(0.005)

                    case {'uieditfield'}
                        if length(ndx)==1 %#ok<ISCL>
                            if isnumeric(val)
                                set(ch(c),'value', sprintf('%.4f', val));
                            else
                                set(ch(c),'value', sprintf('%s', val));
                            end
                        end
                        pause(0.005)

                    case {'uicheckbox'}
                        if length(ndx)==1 %#ok<ISCL>
                            if isnumeric(val)
                                set(ch(c),'value', val>0);
                            else
                                set(ch(c),'value', str2num(val)>0);
                            end
                        end
                        pause(0.005);
                        
                    case {'uidropdown'}
                        if length(ndx)==1 %#ok<ISCL>
                            try
                                if isnumeric(val)
                                    set(ch(c),'valueindex', val);
                                else
                                    set(ch(c),'valueindex', str2num(val));
                                end
                            catch E
                                % redo for older MATLAB versions: no
                                % valueindex exists
                                items = get(ch(c),'items');
                                if isnumeric(val)
                                    set(ch(c),'value',items(val));
                                else
                                    set(ch(c),'value',items(str2num(val)));
                                end
                            end
                        end
                        pause(0.005);

                    case {'uislider','uispinner'}
                        if length(ndx)==1 %#ok<ISCL>
                            if isnumeric(val)
                                set(ch(c),'value', val);
                            else
                                set(ch(c),'value', str2num(val));
                            end
                        end
                        pause(0.005);

                     case {'uibuttongroup'}
                        % get the tag of the selected object.
                        buttons = get(ch(c),'children');
                        % locate which radiobutton has the tag
                        ndx = find(strcmpi(get(buttons,'tag'), val));
                        if length(ndx)>0
                            for but=buttons(:)'
                                set(but,'value',0);
                            end
                            set(buttons(ndx),'value',1);
                        end
               end
            end
        end

        
        function RunCoherence(app, filenames, pars)
            % function for analysis coherence
            
                prefix = ['COH_' pars.var];
            
                % initialize table with no values in the table (rowcount = 0). One
                % string for Id and the rest are doubles
                % T = table('Size', [0, length(pars.chanlocs)+1], ...
                %     'VariableTypes', ["string" repelem("double",length(pars.chanlocs))], ...
                %     'VariableNames', [{'Id'} cellfun(@(x)sprintf('%s_%s',prefix,x),{pars.chanlocs.labels},'uni',0)]);
            
                % initialize output. Save the data into a glocal variable for cohoerence.
                global MeanCOH
                MeanCOH = nan(length(pars.chanlocs), length(pars.chanlocs), length(filenames));
            
                % loop thru the files selected
                for f=1:length(filenames)
                    EEG = pop_loadset([pars.pathname '/' filenames{f}]);
                    % remove channels not in chanlocs
                    remove = find(~ismember({EEG.chanlocs.labels}, {pars.chanlocs.labels}));
                    if ~isempty(remove)
                        EEG = pop_select(EEG, 'nochannel', remove);
                    end
            
                    % check for too many channels missing
                    if EEG.nbchan < length(pars.chanlocs)-pars.MaxMissing
                        warning(sprintf('Too many missing for "%s"', filenames{f}))
                        %continue
                    end
            
            
                    % impute the rest if required
                    if pars.Interpolate
                        EEG = pop_interp(EEG, pars.chanlocs, 'spherical');
                    end
            
                    if pars.IntClean
                        EEG = InterpolationCleaning(EEG);
                    end
            
                    % this takes a bit of time
                    [COH, fs] = fastcoherence(EEG.data(:,:)', 'srate', EEG.srate, 'window', hanning(EEG.srate*4));
            
                    % ndx for frequencies.
                    ndx = fs>=pars.freq(1) & fs<pars.freq(2);
                    % get the positioning of data in the chanlocs
                    [isMatch, idxInChanlocs] = ismember(lower({EEG.chanlocs.labels}), lower({pars.chanlocs.labels}));
                    matchedRows = idxInChanlocs(isMatch);
                    dataRows = find(isMatch);  % Positions in thisX
                    if length(dataRows)~=EEG.nbchan
                        error('Strange mistmatch. Shouldn''t happen.')
                    end
            
                    % idx for channel match
            
                    MeanCOH(matchedRows,matchedRows,f) = mean(COH(:,:, ndx),3);
                end
            
                % if imputation checked (and not iinterpolation) do softimpute
                % data are stores as nfreq x nchan x nsubject, but needs to be nsubject
                % x (nfreq * nchan). Likely.
                if pars.Impute && ~pars.Interpolate
                    tmp = permute(MeanCOH, [3 1 2]); % nsubj x nchan x nfreq
                    reset = all(isnan(tmp(:,:)),2);  % nsubj x (nchan * nfreq)
                    tmp = softimpute(tmp(:,:));
                    tmp = tmp';                      % (nchan  * nfreq) x nsubj
                    MeanCOH = reshape(tmp, size(MeanCOH)); % tested!
                    % reset all fully missing datasets (max number of chans missing
                    % exceeded)
                    MeanCOH(:,:,reset) = nan;
                end
            
                disp('Coherence data stored in global variable MeanCOH')
        end
        
        function RunFAA(app, filenames, pars)
                % function for analysis Frontal Asymmetry
            
                if (pars.Impute)
                    warning('FAA analysis cannot do statistical imputation')
                end
            
                % initialize output. Save the data into a glocal variable for cohoerence.
                global FAA
                FAA = nan(length(filenames),1);
            
                % loop thru the files selected
                for f=1:length(filenames)
                    EEG = pop_loadset([pars.pathname '/' filenames{f}]);
                    % remove channels not in chanlocs
                    remove = find(~ismember({EEG.chanlocs.labels}, {pars.chanlocs.labels}));
                    if ~isempty(remove)
                        EEG = pop_select(EEG, 'nochannel', remove);
                    end
            
                    % impute the rest if required
                    if pars.Interpolate
                        EEG = pop_interp(EEG, pars.chanlocs, 'spherical');
                    end
                    % checked if the channels are in the same order as the
                    % pars.chanlocs and they are!
            
                    if pars.IntClean
                        EEG = InterpolationCleaning(EEG);
                    end
            
                    % this takes a bit of time
                    ndxF3 = ismember({pars.chanlocs.labels}, {'F3'});
                    ndxF4 = ismember({pars.chanlocs.labels}, {'F4'});
                    if ~sum(ndxF3 | ndxF4)
                        % determine not by name but by approximate location... [sph_theta
                        % sph_phi]
                        ideal_F3 = [43.5 29.3];
                        ideal_F4 = [-43.5 29.3];
                        delta = nan(1,length(pars.chanlocs));
                        for ch=1:length(pars.chanlocs)
                            deltaF3(ch) = angularDifference(app, ideal_F3(1), ideal_F3(2), pars.chanlocs(ch).sph_theta, pars.chanlocs(ch).sph_phi);
                            deltaF4(ch) = angularDifference(app, ideal_F4(1), ideal_F4(2), pars.chanlocs(ch).sph_theta, pars.chanlocs(ch).sph_phi);
                        end
                        ndxF3 = minindex(deltaF3);
                        ndxF4 = minindex(deltaF4);
                    end
            
                    [P, fs] = pfft(EEG.data([ndxF3 ndxF4],:)', EEG.srate, hanning(EEG.srate*4), .5);
                    ndx = fs>=pars.freq(1) & fs<pars.freq(2);


                    tmp = log(sum(P(ndx,:),1));
                     % index 1 is F3, index 2 is F4. This is the correct way so that higher
                     % scores mean greater right brain alpha, greter left brain activity,
                     % and therefore apporach related behaviors (positive affect).
                    FAA(f) = tmp(2) - tmp(1);
                end
            
                disp('FAA data stored in global variable FAA.')
        end
        
        function RunPSD(app, filenames, pars)
            % function for analysis PSD
        
            % ask for frequency bounds
            dims = [1 35];  % Textbox dimensions
            definput = {'1.0', '45.0'};  % Default values
            answer = inputdlg({'Enter lower bound:', 'Enter upper bound:'}, ...
                'input lower and upper bound for PSD', dims, definput);
            % Convert the cell array to numbers
            if ~isempty(answer) % Check if user didn't cancel
                lo = round(str2double(answer{1}));
                hi = round(str2double(answer{2}));
            else
                return
            end
        
            % initialize output. Save the data into a glocal variable for cohoerence.
            global AllPSDfreqs
            AllPSDfreqs = lo:(hi-1);
        
            global AllPSD
            AllPSD = nan(length(lo:hi)-1, length(pars.chanlocs), length(filenames));
        
            % loop thru the files selected
            for f=1:length(filenames)
                try
                    EEG = pop_loadset([pars.pathname '/' filenames{f}]);
                    % remove channels not in chanlocs
                    remove = find(~ismember({EEG.chanlocs.labels}, {pars.chanlocs.labels}));
                    if ~isempty(remove)
                        EEG = pop_select(EEG, 'nochannel', remove);
                    end
        
                    % throw an exception when maximum of channels missing is
                    % exceeded
                    if length(pars.chanlocs)-EEG.nbchan>pars.MaxMissing
                        throw('>max channels missing')
                    end
        
                    % impute the rest if required
                    if pars.Interpolate
                        EEG = pop_interp(EEG, pars.chanlocs, 'spherical');
                    end
        
                    %
                    if pars.IntClean
                        EEG = InterpolationCleaning(EEG);
                    end
        
                    % get the positioning of data in the chanlocs
                    [isMatch, idxInChanlocs] = ismember(lower({EEG.chanlocs.labels}), lower({pars.chanlocs.labels}));
                    matchedRows = idxInChanlocs(isMatch);
                    dataRows = find(isMatch);  % Positions in thisX
                    if length(dataRows)~=EEG.nbchan
                        error('mismatch')
                    end
        
                    % this takes a bit of time
                    [P, fs] = pfft(EEG.data(:,:)', EEG.srate, hanning(EEG.srate*4), .5);
        
                    cnt = 0;
                    for frq=lo:(hi-1)
                        cnt = cnt+1;
                        ndx = fs>=frq & fs<(frq+1);
                        if pars.dB
                            AllPSD(cnt,matchedRows,f) = 10*log10(sum(P(ndx,:),1));
                        else
                            AllPSD(cnt,matchedRows,f) = sum(P(ndx,:),1);
                        end
                    end
                catch E
                    if strcmp(E.message,'mismatch')
                        throw(E);
                        % this shouldn't happen
                    else
                        AllPSD(:,:,f) = nan;
                    end
                end
            end
        
            % if imputation checked (and not iinterpolation) do softimpute
            % data are stores as nfreq x nchan x nsubject, but needs to be nsubject
            % x (nfreq * nchan). Likely.
            if pars.Impute && ~pars.Interpolate
                tmp = permute(AllPSD, [3 1 2]); % nsubj x nchan x nfreq
                reset = all(isnan(tmp(:,:)),2); % nsubj x (nchan * nfreq)
                tmp = softimpute(tmp(:,:));
                tmp = tmp';                     % (nchan * nfreq) x nsubj
                AllPSD = reshape(tmp, size(AllPSD));
                % reset all fully missing dataset (max number of chans missing
                % exceeded)
                AllPSD(:,:,reset) = nan;
            end
        
        
            disp('Data stored in global AllPSD (freqs in AllPSDfreqs). Filenames in ResultTable.')
        
            % open a figure for the PSD
            chanlocs = pars.chanlocs;
        
            fig = figure('Name', 'plot Power Spectrum', 'Tag', 'figPSD', 'NumberTitle', 'off', ...
                         'Position', [100, 100, 700, 500]); % Adjust size as needed
            % Create a frame (uipanel), checkbox, and create axes inside the panel
            frame = uipanel('Parent', fig, 'Title', 'Power spectrum', 'Tag', 'framePSD', ...
                            'Position', [0.005, 0.1, .98, 0.9]); % [x, y, width, height]
            ax = axes('Parent', frame, 'Position', [0.07, 0.1, .91, 0.88], 'Tag', 'axesPSD'); % Adjust within panel
            checkbox = uicontrol('Style', 'checkbox', 'Parent', fig, 'Tag', 'cbSummary', ...
                                 'String', 'Summarize into regions', 'Fontsize', 11, ...
                                 'Units', 'normalized', ...
                                 'Position', [0.05, 0.03, 0.4, 0.05], ...
                                 'Callback', @(src, event) checkbox_callback(app, src, ax, AllPSDfreqs, AllPSD, chanlocs, 10, pars.dB));
        
            checkbox_callback(app, checkbox, ax, AllPSDfreqs, AllPSD, chanlocs, 10, pars.dB);
        end
        
        function RunMSE(app, filenames, pars)
            % function for analysis PSD
        
            % ask for frequency bounds
            dims = [1 35];  % Textbox dimensions
            definput = {'1', '25'};  % Default values
            answer = inputdlg({'Enter lower bound:', 'Enter upper bound:'}, ...
                'input lower and upper bound for MSE scales', dims, definput);
            % Convert the cell array to numbers
            if ~isempty(answer) % Check if user didn't cancel
                lo = round(str2double(answer{1}));
                hi = round(str2double(answer{2}));
            else
                return
            end
        
            % initialize output. Save the data into a glocal variable for cohoerence.
            global AllMSEScales
            AllMSEScales = lo:hi;
        
            global AllMSE
            AllMSE = nan(length(lo:hi), length(pars.chanlocs), length(filenames));
        
            system('python3 udplistener.py 1234 &');
            
            % loop thru the files selected
            for f=1:length(filenames)
                try
                    EEG = pop_loadset([pars.pathname '/' filenames{f}]);
                    % remove channels not in chanlocs
                    remove = find(~ismember({EEG.chanlocs.labels}, {pars.chanlocs.labels}));
                    if ~isempty(remove)
                        EEG = pop_select(EEG, 'nochannel', remove);
                    end
        
                    % throw an exception when maximum of channels missing is
                    % exceeded
                    if length(pars.chanlocs)-EEG.nbchan>pars.MaxMissing
                        throw('>max channels missing')
                    end
        
                    % impute the rest if required
                    if pars.Interpolate
                        EEG = pop_interp(EEG, pars.chanlocs, 'spherical');
                    end
        
                    % do interpolation cleaning when selected
                    if pars.IntClean
                        EEG = InterpolationCleaning(EEG);
                    end
        
                    % get the positioning of data in the chanlocs
                    [isMatch, idxInChanlocs] = ismember(lower({EEG.chanlocs.labels}), lower({pars.chanlocs.labels}));
                    matchedRows = idxInChanlocs(isMatch);
                    dataRows = find(isMatch);  % Positions in thisX
                    if length(dataRows)~=EEG.nbchan
                        error('mismatch')
                    end
        
                    % this takes a bit of time... 
                    parfor ch=1:EEG.nbchan
                        res{ch} = []
                        res{ch} = multiscaleSampleEntropy(EEG.data(ch,:), 2, 1.2, lo:hi, EEG.srate*4);
                    end
                    
                    for ch=1:EEG.nbchan
                        AllMSE(:,matchedRows(ch),f) = res{ch};
                    end
                catch E
                    if strcmp(E.message,'mismatch')
                        throw(E);
                        % this shouldn't happen
                    else
                        AllMSE(:,:,f) = nan;
                    end
                end
            end
        
            % if imputation checked (and not iinterpolation) do softimpute
            % data are stores as nfreq x nchan x nsubject, but needs to be nsubject
            % x (nfreq * nchan). Likely.
            if pars.Impute && ~pars.Interpolate
                tmp = permute(AllMSE, [3 1 2]); % nsubj x nchan x nfreq
                reset = all(isnan(tmp(:,:)),2); % nsubj x (nchan * nfreq)
                tmp = softimpute(tmp(:,:));
                tmp = tmp';                     % (nchan * nfreq) x nsubj
                AllMSE = reshape(tmp, size(AllMSE));
                % reset all fully missing dataset (max number of chans missing
                % exceeded)
                AllMSE(:,:,reset) = nan;
            end
        
        
            disp('Data stored in global AllMSE (scales in AllMSEScales). Filenames in ResultTable.')
        
        end

        function RunSingleParameterExtraction(app, filenames, pars)
            % function for analysis of EEG extracting a single parameter per
            % channel
            
                global ResultTable
            
                % initialize based on the analysis selected (var)
                switch pars.var
                    case 'IAF'
                        prefix = pars.var;
                        % if var is IAF, ask for lower and upper values for alpha as that
                        % may be different from the dropdown menu
                        dims = [1 35];  % Textbox dimensions
                        definput = {'7.0', '13.0'};  % Default values
                        answer = inputdlg({'Enter lower bound:', 'Enter upper bound:'}, ...
                            'input frequency band for IAF estimation', dims, definput);
                        % Convert the cell array to numbers
                        if ~isempty(answer) % Check if user didn't cancel
                            lo = str2double(answer{1});
                            hi = str2double(answer{2});
                        else
                            return
                        end
            
                    case {'Power','DFA'}
                        prefix = sprintf('%s_%s', pars.var, pars.freqname);
                        % determine frequency for Power and DFA
                end
            
                % init data
                X = nan(length(filenames), length(pars.chanlocs));
                % loop thru the files selected
                for f=1:length(filenames)
                    EEG = pop_loadset([pars.pathname '/' filenames{f}]);

                    if pars.EOGClean
                        eogndx = contains(lower({EEG.chanlocs.labels}),'eog');
                        if (sum(eogndx))
                            eog = FindEOGComp(EEG, EEG.data(eogndx,:), .7);
                            eog = eog(eog>0);
                            if ~isempty(eog)
                                % this formula works! Checked! Multiple ICs will be
                                % extracted this way from the full set of channels.
                                EEG.data(:,:) = EEG.data(:,:) - EEG.icawinv(:,eog)*EEG.icaact(eog,:);
                            end
                        end
                    end
            
            
                    % check for renamed channels P7/P8 --> T5/T6
                    if any(ismember(lower({EEG.chanlocs.labels}), lower({'P7','P8'}))) ...
                            && any(ismember(lower({pars.chanlocs.labels}), lower({'T5','T6'})))
                        for ch=1:length(EEG.chanlocs)
                            if strcmpi(EEG.chanlocs(ch).labels, 'P7')
                                EEG.chanlocs(ch).labels = 'T5';
                            elseif strcmpi(EEG.chanlocs(ch).labels, 'P8')
                                EEG.chanlocs(ch).labels = 'T6';
                            end
                        end
                    elseif any(ismember(lower({EEG.chanlocs.labels}), lower({'T5','T6'}))) ...
                            && any(ismember(lower({pars.chanlocs.labels}), lower({'P7','P8'})))
                        for ch=1:length(EEG.chanlocs)
                            if strcmpi(EEG.chanlocs(ch).labels, 'T5')
                                EEG.chanlocs(ch).labels = 'P7';
                            elseif strcmpi(EEG.chanlocs(ch).labels, 'T6')
                                EEG.chanlocs(ch).labels = 'P8';
                            end
                        end
                    end
            
                    % remove channels not in chanlocs
                    remove = find(~ismember({EEG.chanlocs.labels}, {pars.chanlocs.labels}));
                    if ~isempty(remove)
                        EEG = pop_select(EEG, 'nochannel', remove);
                    end
            
                    % check for too many channels missing
                    if EEG.nbchan < length(pars.chanlocs)-pars.MaxMissing
                        warning(sprintf('Too many missing for "%s"', filenames{f}))
                        %continue
                    end
            
                    % impute the rest. This should also reorder the channels, even if
                    % there is no imputation required
                    if pars.Interpolate
                        try
                            EEG = pop_interp(EEG, pars.chanlocs, 'spherical');
                        catch E
                            if strcmpi(E.message, 'Interpolation require channel location')
                                [~,cmdout] = system(sprintf('find %s -name "*standard_1005.elc"', fileparts(which('eeglab'))));
                                EEG = pop_chanedit(EEG, 'lookup', cmdout);
                            else
                                error(E.message)
                            end
                            EEG = pop_interp(EEG, pars.chanlocs, 'spherical');
                        end
                    end
            
                    if pars.IntClean
                        EEG = InterpolationCleaning(EEG);
                    end
            
                    % do calculations
                    switch pars.var
                        case 'DFA'
                            val = dfa(abs(hilbert(filter_fir(EEG.data, EEG.srate, pars.freq(1), pars.freq(2), 3.0, true)')), EEG.srate);
            
                        case 'IAF'
                            [P,fs] = pfft(EEG.data(:,:)', EEG.srate, hanning(EEG.srate*4), .5);
                            ndx = fs>=lo & fs<hi;
                            val = sum(P(ndx,:).*(fs(ndx)'),1) ./ sum(P(ndx,:),1);
            
                        case 'Power'
                            [P,fs] = pfft(EEG.data(:,:)', EEG.srate, hanning(EEG.srate*4), .5);
                            ndx = fs>=pars.freq(1) & fs<pars.freq(2);
                            val = sum(P(ndx,:),1);
                            %val(val<=0)=eps;
                            %val=10*log10(val);
                            if pars.dB
                                val = 10*log10(val);
                            end
                            disp(size(val))
                    end
            
                    % get the positioning of data in the chanlocs
                    [isMatch, idxInChanlocs] = ismember(lower({EEG.chanlocs.labels}), lower({pars.chanlocs.labels}));
                    matchedRows = idxInChanlocs(isMatch);
                    dataRows = find(isMatch);  % Positions in thisX
                    if length(dataRows)~=EEG.nbchan
                        error('mismatch')
                    end
            
                    X(f, matchedRows) = val;
                end
            
                if pars.Impute
                    reset = all(isnan(X), 2);
                    X = softimpute(X);
                    X(reset,:) = nan;
                end
            
                % initialize table with no values in the table (rowcount = 0). One
                % string for Id and the rest are doubles
                ResultTable = table('Size', [length(filenames), length(pars.chanlocs)+1], ...
                    'VariableTypes', ["string" repelem("double",length(pars.chanlocs))], ...
                    'VariableNames', [{'Id'} cellfun(@(x)sprintf('%s_%s',prefix,x),{pars.chanlocs.labels},'uni',0)]);
            
                ResultTable.Id = filenames(:);
                ResultTable(:,2:end) = array2table(X);
            
                % save the table
                [outfilename, outpathname] = uiputfile('*.txt', 'Save As Tab-delimted output');
                if outfilename ~= 0
                    writetable(ResultTable,[outpathname '/' outfilename], 'Delimiter', '\t');
                    disp('file written')
                else
                    warning('No file written')
                end
            
                fprintf('Data Table stored in global variable ResultTable\n')


                % plotting the data in ResultTable
                toPlot = nanmean(table2array(ResultTable(:,2:end)));
                figure;
                cmin = min(toPlot(:));
                cmax = max(toPlot(:));
                topoplot((toPlot), pars.chanlocs, 'maplimits', [cmin*.98 cmax*.98])
                colorbar
        end
        
        function RunTF(app, filenames, pars)
                % function for time frequency analysis
            
                % ask for frequency bounds
                dims = [1 35];  % Textbox dimensions
                definput = {'4.0', '45.0', '[2 .5]'};  % Default values
                answer = inputdlg({'Enter lower bound:', 'Enter upper bound:', 'rolloff parameters:'}, ...
                    'input lower and upper bound for TF analysis', dims, definput);
                % Convert the cell array to numbers
                if ~isempty(answer) % Check if user didn't cancel
                    lo = round(str2double(answer{1}));
                    hi = round(str2double(answer{2}));
                    rolloff = str2double(answer{3});
                else
                    return
                end
            
                % initialize output. Save the data into a glocal variable for coherence.
                global AllTFtimes
                global AllTFfreqs
                AllTFfreqs = lo:hi;
            
                global AllTF AllERP AllITC
                % do not initialize, wait until the first analysis is done and then initialize
                % AllTF = nan(length(AllTFfreqs), length(pars.chanlocs), length(filenames));
            
                % loop thru the files selected
                for f=1:length(filenames)
                    EEG = pop_loadset([pars.pathname '/' filenames{f}]);
            
                    % remove channels not in chanlocs
                    remove = find(~ismember({EEG.chanlocs.labels}, {pars.chanlocs.labels}));
                    if ~isempty(remove)
                        EEG = pop_select(EEG, 'nochannel', remove);
                    end
            
                    if f==1
                        AllERP = nan(EEG.nbchan, EEG.pnts, length(filenames));
                        AllTF = nan(size(ERSP,1), size(ERSP,2), EEG.nbchan, length(filenames));
                        AllITC = nan(size(ERSP,1), size(ERSP,2), EEG.nbchan, length(filenames));
                    end
            
                    % throw an exception when maximum of channels missing is
                    % exceeded
                    if length(pars.chanlocs)-EEG.nbchan>pars.MaxMissing
                        continue
                    end
            
                    % impute the rest if required
                    if pars.Interpolate
                        EEG = pop_interp(EEG, pars.chanlocs, 'spherical');
                    end
            
                    if pars.IntClean
                        EEG = InterpolationCleaning(EEG);
                    end
            
                    % check if data is epoched.
                    if EEG.trials==1
                        % data needs to be epoched!
                        if f==1
                            % ask for what epochs to analyse!
                            answer = inputdlg({'Enter space-separated event types','pre-stimulus period (ms)','post-stimulus period (ms)'}, ...
                                        'Event types selection', dims, {sprintf('%d ',unique([EEG.event.type])),'',''});
                            if isempty(answer)
                                return
                            end
                            evttypes = strsplit(answer{1});
                            epoching = str2num(sprintf('[-%f %s]',abs(str2num(answer{2})),answer{3}));
                        end
            
                        % epoch by numeric or string
                        EEG = pop_epoch(EEG, [cellfun(@str2num,evttypes,'uni',0), evttypes], epoching/1000);
                    end
                    % else assume data already epoched.
            
                    % get ERPs
                    AllERP(:,:,f) = mean(EEG.data,3);
            
                    for ch=1:EEG.nbchan
                        [ERSP, ITC, ~, TIMES, FREQS] = pop_newtimef(EEG, 1, ch, [], [2 .5], 'freqs', lo:hi, ...
                            'wletmethod', 'dftfilt2', 'timesout', 200,...
                            'plotersp', 'off', 'plotitc', 'off');
                        [~,idx] = ismember(lower(EEG.chanlocs(ch).labels), lower({pars.chanlocs.labels}));
                        AllTF(:,:,idx,f) = ERSP;
                        AllITC(:,:,idx,f) = ITC;
                        AllTFfreqs = FREQS;
                        AllTFtimes = TIMES;
                    end
            
                end
            
                % if imputation checked (and not iinterpolation) do softimpute
                % data are stores as nfreq x nchan x nsubject, but needs to be nsubject
                % x (nfreq * nchan). Likely.
                if pars.Impute && ~pars.Interpolate
                    tmp = permute(AllTF, [4 1 2 3]); % nsubj x nchan x nchan x nfreq
                    reset = all(isnan(tmp(:,:)),2);  % nsubj x (nchan * nchan * nfreq)
                    tmp = softimpute(tmp(:,:));
                    tmp = tmp';                      % (nchan * nchan * nfreq) x nsubj
                    AllTF = reshape(tmp, size(AllTF)); % tested!
                    % reset all fully missing datasets (max number of chans missing
                    % exceeded)
            
                    AllITC(:,:,:, reset) = nan;
                    tmp = permute(AllITC, [4 1 2 3]); % nsubj x nchan x nchan x nfreq
                    reset = all(isnan(tmp(:,:)),2);  % nsubj x (nchan * nchan * nfreq)
                    tmp = softimpute(tmp(:,:));
                    tmp = tmp';                      % (nchan * nchan * nfreq) x nsubj
                    AllITC = reshape(tmp, size(AllITC)); % tested!
                    % reset all fully missing datasets (max number of chans missing
                    % exceeded)
                    AllITC(:,:,:, reset) = nan;
                end
            
            
                disp('Data stored as: global AllTF AllITC AllTFfreqs AllTFtimes AllERP filenames')
        end
        
        function angle_diff = angularDifference(app, theta1, phi1, theta2, phi2)
                % Convert degrees to radians
                theta1 = deg2rad(theta1);
                phi1 = deg2rad(phi1);
                theta2 = deg2rad(theta2);
                phi2 = deg2rad(phi2);
            
                % Convert to Cartesian unit vectors
                A = [cos(phi1) * cos(theta1), cos(phi1) * sin(theta1), sin(phi1)];
                B = [cos(phi2) * cos(theta2), cos(phi2) * sin(theta2), sin(phi2)];
            
                % Compute the angular difference using the dot product
                cos_alpha = dot(A, B); % No need to divide by magnitude since they are unit vectors
                cos_alpha = max(min(cos_alpha, 1), -1); % Ensure numerical stability
            
                % Compute the angle in degrees
                angle_diff = rad2deg(acos(cos_alpha));
        end
        
        function checkbox_callback(app, hObject, ax, fs, P, chanlocs, fontsize, dB)
            
            
                % plot either all channels or a summary
                if get(hObject,'Value')==0
                    x = fs;
                    y = nanmean(P,3);
                    newx = min(fs):.25:max(fs);
                    res = arrayfun(@(c)spline(x, y(:,c), newx)', 1:size(y,2), 'UniformOutput', false);
                    newy = cell2mat(res);
                    plot(ax, newx, newy);
                    legend({chanlocs.labels})
            
                else
                    numlabels = {'theta','radius','X','Y','Z','sph_theta','sph_phi','sph_radius'};
                    tab = struct2table(chanlocs);
                    % repair: convert cell array of double to double with missings
                    for lab=1:length(numlabels)
                        if ismember(numlabels{lab}, tab.Properties.VariableNames) && ~isnumeric(tab.(numlabels{lab}))
                            values = cellfun(@(x)ifthen(isempty(x), nan, double(x)), tab.(numlabels{lab}));
                            tab.(lab) = values;
                        end
                    end
            
                    relX = tab.X ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
                    relY = tab.Y ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
                    % relZ = tab.Z ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
            
                    ant    = relX>=-1E-5;
                    post   = ~ant;
                    medial = abs(relY)<.41;
                    left   = relY>=.41;
                    right  = relY<=.41;
            
                    regP = nan(size(P,1),6);
                    regP(:,1) = mean(P(:,ant & left),2);
                    regP(:,2) = mean(P(:,ant & medial),2);
                    regP(:,3) = mean(P(:,ant & right),2);
                    regP(:,4) = mean(P(:,post & left),2);
                    regP(:,5) = mean(P(:,post & medial),2);
                    regP(:,6) = mean(P(:,post & right),2);
            
                    x = fs(ndx);
                    y = regP(ndx,:);
                    newx = min(fs(ndx)):.25:max(fs(ndx));
                    res = arrayfun(@(c)spline(x, y(:,c), newx)', 1:size(y,2), 'UniformOutput', false);
                    newy = cell2mat(res);
                    plot(ax, newx, newy);
                    legend('ant left','ant medial','ant right','post left','post medial','post right')
            
                end
                set(gca, 'fontsize', fontsize+2)
                xlabel('frequency (Hz)')
                ylabel('Power({\mu}V^2/Hz)')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EegBatchExtract_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to EegBatchExtract (see VARARGIN)
            
            % Choose default command line output for EegBatchExtract
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes EegBatchExtract wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            % initialise data
            data = guidata(hObject);
            
            % set the values of the uicontrols. Read
            if ismac()
                data.INIDIR = '~/Application Support/Matlab_EegBatchExtract';
            elseif isunix
                data.INIDIR = '~/.config/Matlab_EegBatchExtract';
            elseif ispc
                data.INIDIR = '~/AppData/Matlab_EegBatchExtract';
            else
                warning('unknown system')
                data.INIDIR = './';
            end
            
            % initialize eeglab if not yet done
            if isempty(which('pop_loadset.m'))
                try
                    eeglab
                catch
                    warning('No eeglab found in path')
                end
            end
            
            % initialize
            data.EEG = eeg_emptyset();
            
            % set the values of the uicontrols
            skipnext = false;
            try
                FN = sprintf('%s/%s.ini', data.INIDIR, get(hObject,'name'));
                opts = detectImportOptions(FN, 'FileType','text');
                % Force column "ID" to be read as string
                opts = setvartype(opts, 'val', 'string');
                strlist = readtable(FN, opts);
            catch E
                warning('Initialization file read failed. Will be created on close.')
                skipnext = true;
            end
            
            if ~skipnext
                try
                    SetUIControlData(app, hObject, strlist);
                catch E
                    warning('Setting the saved settings failed.')
                end
            end
                
            
            % get the most recently used filenames
            FN = sprintf('%s/%s_pathfilenames.ini', data.INIDIR, get(hObject,'name'));
            fid = fopen(FN, 'r');
            pathname = [];
            filenames = {};
            if fid>0
                pathname = fgetl(fid);
                if pathname~=-1
                    filenames{1} = fgetl(fid);
                    while filenames{end} ~= -1
                        filenames{end+1} = fgetl(fid);
                    end
                    if filenames{end} == -1
                        filenames = filenames(1:end-1);
                    end
                end
            end
            data.pathname = pathname;
            data.filenames = filenames;
            
            data.editSelectPath.String = pathname;
            data.editSelectFileNum.String = sprintf("%d files",length(filenames));
            
            % now the impute file path, filename, and number of channels:
            % put path and filename in editbox, file third edit box with channels
            pathname = data.editImputePath.String;
            filename = data.editImputeFilename.String;
            try
                PlotEEG = pop_loadset([pathname '/' filename]);
                data.chanlocs = PlotEEG.chanlocs;
                data.editImputeChannels.String = sprintf('%s ', data.chanlocs.labels);
                data.editImputeChannels.Tooltip = sprintf('%d channels will be selected from each file (when available)', length(data.chanlocs));
            catch
                warning('Undefined error loading the imputation/channel selection dataset')
            end
            
            
            guidata(hObject, data)
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            
            data = guidata(hObject);
            
            % save all the settings
            try
                strlist = GetUIControlData(app, hObject);
                if ~exist(data.INIDIR)
                    mkdir(data.INIDIR);
                end
                writetable(strlist,sprintf('%s/%s.ini', data.INIDIR, get(hObject,'name')),...
                    'delimiter','\t','filetype','text',...
                    "QuoteStrings",true);
            catch E
                warning('Saving settings failed.')
            end
            
            if isfield(data, 'filenames')
                FN = sprintf('%s/%s_pathfilenames.ini', data.INIDIR, get(hObject,'name'));
                fid = fopen(FN, 'w');
                if fid>0
                    fprintf(fid,'%s\n',data.pathname);
                    for f=1:length(data.filenames)
                        fprintf(fid,'%s\n',data.filenames{f});
                    end
                    fclose(fid);
                end
            end
            
            
            delete(hObject);
        end

        % Button pushed function: pushbuttonBatch
        function pushbuttonBatch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonBatch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            % always create the result table with filenames
            global ResultTable
            
            
            % predefined
            freqnames = {'alpha','beta','theta','delta','alphalo','alphahi','betalo','betahi','SENSEalpha','SENSEtheta','ENIGMA alpha'};
            freqs = {[8 13], [13 30], [4 8], [1 3], [7 9.5], [9.5 13], [13 21], [21 30], [8.5 12.0], [3.0 8.5], [7.0 13.0]};
            
            
            % what analysis was selected?
            selectedButton = data.uibuttongroup.SelectedObject;
            var = selectedButton.String;
            
            if ismember(var, {'DFA','Power'}) && data.popupmenuFreq.Value==1
                errordlg('No frequency selected!')
                return
            end
            
            pars = struct;
            try
                pars.freqname = freqnames{data.popupmenuFreq.Value-1};
                pars.freq = freqs{data.popupmenuFreq.Value-1};
            catch
                pars.freqname = '';
                pars.freq = [];
            end
            pars.pathname = data.pathname;
            pars.var = var;
            pars.chanlocs = data.chanlocs;
            pars.dB = data.checkboxdB.Value;
            pars.IntClean = data.checkboxInterpolationCleaning.Value;
            pars.EOGClean = data.checkboxRunICAfilter.Value;
            pars.MaxMissing = data.spinnerMaxMissing.Value;
            pars.Interpolate = data.checkboxInterpolate.Value>0;
            pars.Impute = data.checkboxImpute.Value>0;
            
            
            % initialize table with no values in the table (rowcount = 0). One
            % string for Id (and fill with filenames)
            ResultTable = table('Size', [length(data.filenames), 1], 'VariableTypes', "string", 'VariableNames', {'Id'});
            ResultTable.Id(:) = data.filenames;
            
            
            % DFA, IAF, and Power have the same output format.
            if ismember(var, {'DFA','IAF','Power'})
                RunSingleParameterExtraction(app, data.filenames, pars);
            
            elseif ismember(var, {'Coherence'})
                RunCoherence(app, data.filenames, pars);
            
            elseif ismember(var, {'PSD'})
                RunPSD(app, data.filenames, pars);

            elseif ismember(var, {'MSE'})
                RunMSE(app, data.filenames, pars);
            
            elseif ismember(var, {'FAA'})
                RunFAA(app, data.filenames, pars);
            
            elseif ismember(var, {'ERP/ERSP'})
                RunTF(app, data.filenames, pars);
            
            end
        end

        % Button pushed function: pushbuttonChanlocs
        function pushbuttonChanlocs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonChanlocs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            FilterSpec = {'*.set', 'EEGLAB'};
            defaultpath = data.editImputePath.String;
            if iscell(defaultpath)
                defaultpath = defaultpath{1};
            end
            if ~exist(defaultpath)
                defaultpath = '.';
            end
            % fid = fopen(sprintf('%s/%s_DefaultPath.ini','r');
            % if fid>0
            %     try
            %         DefaultPath = fgetl(fid);
            %         fclose(fid);
            %     catch
            %     end
            % end
            [filename, pathname, ~] = uigetfile(FilterSpec, ...
                'Select an EEGLAB file with channel locations', defaultpath);
            
            % Check if the user selected files or canceled
            if isequal(filename, 0)
                disp('User canceled file selection.');
                return;
            end
            
            % put path and filename in editbox, file third edit box with channels
            data.editImputePath.String = pathname;
            data.editImputeFilename.String = '...';
            pause(.01)
            PlotEEG = pop_loadset([pathname '/' filename]);
            data.chanlocs = PlotEEG.chanlocs;
            data.editImputeFilename.String = filename;
            data.editImputeChannels.String = sprintf('%s ', data.chanlocs.labels);
            data.editImputeChannels.Tooltip = sprintf('%d channels will be selected from each file (when available)', length(data.chanlocs));
            
            guidata(hObject, data);
        end

        % Button pushed function: pushbuttonSelect
        function pushbuttonSelect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSelect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            FilterSpec = {'*.set', 'EEGLAB'};
            defaultpath = data.editSelectPath.String;
            if iscell(defaultpath)
                defaultpath = defaultpath{1};
            end
            if ~exist(defaultpath)
                defaultpath = '.';
            end
            
            % fid = fopen(sprintf('%s/%s_pathfilenames.ini', data.INIDIR, get(hObject,'name')), 'r');
            % defaultpath = ".";
            % if fid>0
            %     try
            %         defaultpath = fgetl(fid);
            %         fclose(fid);
            %     catch
            %     end
            % end
            [filenames, pathname, filterindex] = uigetfile(FilterSpec, ...
                'Select an EEGLAB file', defaultpath, ...
                'multiselect', 'on');
            
            % Check if the user selected files or canceled
            if isequal(filenames, 0)
                disp('User canceled file selection.');
                return;
            end
            
            % Ensure filenames is a cell array (single selection returns a char)
            if ischar(filenames)
                filenames = {filenames};
            end
            
            % save the path when not cancelled
            % fid = fopen(sprintf('%s/%s_pathfilenames.ini', data.INIDIR, get(hObject,'name')), 'w');
            % if fid>0
            %     fprintf(fid,'%s\n',pathname);
            %     for f=1:length(filenames)
            %         fprintf(fid, '%s\n', filanems{f})
            %     end
            %     fclose(fid);
            % else
            %     warning('Could not open file for writing')
            % end
            
            % save in struct and wait for next buttonpress
            data.filenames = filenames;
            data.pathname = pathname;
            
            data.editSelectPath.String = pathname;
            data.editSelectFileNum.String = sprintf('%d files', length(filenames));
            
            guidata(hObject,data)
        end

        % Button pushed function: pushbuttonStats
        function pushbuttonStats_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonStats (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            error('Not ready yet!')
            
            data = guidata(hObject);
            
            h = figStatsModal(hObject);
            uiwait(h);
            
            question1 = 'test variable';
            question2 = 'grouping or 2nd variable';
            options = {'2D', '3D within subject', '3D between subject'};
            
            res = Stats_modal_dialog(question1, question2, options);
            
            if strcmp(res.pressed, 'ok')
                fprintf('You entered: %s\n', res.text);
                fprintf('You chose: %s\n', res.choice);
            else
                disp('User canceled.');
            end
        end

        % Callback function
        function sliderMaxMissing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderMaxMissing (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            
            data.textMaxMissing.String = sprintf('max. missing %d',get(hObject,'Value'));
            
            guidata(hObject, data);
        end

        % Value changed function: checkboxInterpolate
        function checkboxInterpolateValueChanged(app, event)
            value = app.checkboxInterpolate.Value;
            if (value>0)
                app.checkboxImpute.Value = 0; 
            end
        end

        % Callback function
        function checkboxImputeValueChanged(app, event)
 
        end

        % Value changed function: checkboxImpute
        function checkboxImputeValueChanged2(app, event)
            value = app.checkboxImpute.Value;
            if (value>0)
                app.checkboxInterpolate.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'EegBatchExtract';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.pushbuttonSelect = uibutton(app.figure1, 'push');
            app.pushbuttonSelect.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSelect_Callback, true);
            app.pushbuttonSelect.Tag = 'pushbuttonSelect';
            app.pushbuttonSelect.Tooltip = 'Select EEGLAB files';
            app.pushbuttonSelect.Text = 'Select files';
            app.pushbuttonChanlocs = uibutton(app.figure1, 'push');
            app.pushbuttonChanlocs.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonChanlocs_Callback, true);
            app.pushbuttonChanlocs.Tag = 'pushbuttonChanlocs';
            app.pushbuttonChanlocs.Tooltip = 'Select file with full channel locations for channel selection and or imputation';
            app.pushbuttonChanlocs.Text = 'Impute & Select';
            app.editImputeFilename = uieditfield(app.figure1, 'text');
            app.editImputeFilename.Tag = 'editImputeFilename';
            app.pushbuttonBatch = uibutton(app.figure1, 'push');
            app.pushbuttonBatch.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonBatch_Callback, true);
            app.pushbuttonBatch.Tag = 'pushbuttonBatch';
            app.pushbuttonBatch.Text = 'Batch';
            app.editSelectPath = uieditfield(app.figure1, 'text');
            app.editSelectPath.Tag = 'editSelectPath';
            app.editSelectFileNum = uieditfield(app.figure1, 'text');
            app.editSelectFileNum.Tag = 'editSelectFileNum';
            app.uibuttongroup = uibuttongroup(app.figure1);
            app.uibuttongroup.Title = 'Analysis';
            app.uibuttongroup.Tag = 'uibuttongroup';
            app.radiobuttonDFA = uiradiobutton(app.uibuttongroup);
            app.radiobuttonDFA.Tag = 'radiobuttonDFA';
            app.radiobuttonDFA.Text = 'DFA';
            app.radiobuttonDFA.Value = true;
            app.radiobuttonCoh = uiradiobutton(app.uibuttongroup);
            app.radiobuttonCoh.Tag = 'radiobuttonCoh';
            app.radiobuttonCoh.Text = 'Coherence';
            app.radiobuttonPSD = uiradiobutton(app.uibuttongroup);
            app.radiobuttonPSD.Tag = 'radiobuttonPSD';
            app.radiobuttonPSD.Text = 'PSD';
            app.radiobuttonIAF = uiradiobutton(app.uibuttongroup);
            app.radiobuttonIAF.Tag = 'radiobuttonIAF';
            app.radiobuttonIAF.Text = 'IAF';
            app.radiobuttonPower = uiradiobutton(app.uibuttongroup);
            app.radiobuttonPower.Tag = 'radiobuttonPower';
            app.radiobuttonPower.Text = 'Power';
            app.radiobuttonFAA = uiradiobutton(app.uibuttongroup);
            app.radiobuttonFAA.Tag = 'radiobuttonFAA';
            app.radiobuttonFAA.Tooltip = 'Frontal alpha asymmetry (F3-F4)';
            app.radiobuttonFAA.Text = 'FAA';
            app.radiobuttonTF = uiradiobutton(app.uibuttongroup);
            app.radiobuttonTF.Tag = 'radiobuttonTF';
            app.radiobuttonTF.Text = 'ERP/ERSP';
            app.radiobuttonReview = uiradiobutton(app.uibuttongroup);
            app.radiobuttonReview.Tag = 'radiobuttonReview';
            app.radiobuttonReview.Text = 'Review';
            app.radiobuttonMSE = uiradiobutton(app.uibuttongroup);
            app.radiobuttonMSE.Tag = 'radiobuttonMSE';
            app.radiobuttonMSE.Text = 'MSE';
            app.popupmenuFreq = uidropdown(app.figure1);
            app.popupmenuFreq.Items = {'<freq.>', 'alpha (8-13)', 'beta (13-30)', 'theta (4-8)', 'delta (1-4)', 'lower alpha', 'upper alpha', 'lower beta', 'upper beta', 'SENSE alpha 8.5-11.5', 'SENSE theta 3.0-8.0', 'ENIGMA alpha'};
            app.popupmenuFreq.Tag = 'popupmenuFreq';
            app.popupmenuFreq.Value = 'alpha (8-13)';
            app.checkboxdB = uicheckbox(app.figure1);
            app.checkboxdB.Tag = 'checkboxdB';
            app.checkboxdB.Text = 'dB conversion';
            app.pushbuttonStats = uibutton(app.figure1, 'push');
            app.pushbuttonStats.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStats_Callback, true);
            app.pushbuttonStats.Tag = 'pushbuttonStats';
            app.pushbuttonStats.Text = 'Permutation';
            app.checkboxInterpolationCleaning = uicheckbox(app.figure1);
            app.checkboxInterpolationCleaning.Tag = 'checkboxInterpolationCleaning';
            app.checkboxInterpolationCleaning.Text = 'clean by interpolation';
            app.checkboxRunICAfilter = uicheckbox(app.figure1);
            app.checkboxRunICAfilter.Tag = 'checkboxRunICAfilter';
            app.checkboxRunICAfilter.Text = 'Run ICA EOG filter';
            app.editImputePath = uieditfield(app.figure1, 'text');
            app.editImputePath.Tag = 'editImputePath';
            app.checkboxInterpolate = uicheckbox(app.figure1);
            app.checkboxInterpolate.ValueChangedFcn = createCallbackFcn(app, @checkboxInterpolateValueChanged, true);
            app.checkboxInterpolate.Tag = 'checkboxInterpolate';
            app.checkboxInterpolate.Tooltip = 'Use missing channel interpolation before feature extraction';
            app.checkboxInterpolate.Text = 'Interpolate missing channels';
            app.textMaxMissing = uilabel(app.figure1);
            app.textMaxMissing.Tag = 'textMaxMissing';
            app.textMaxMissing.Text = '';
            app.editImputeChannels = uieditfield(app.figure1, 'text');
            app.editImputeChannels.Tag = 'editImputeChannels';
            app.maxmissingSpinnerLabel = uilabel(app.figure1);
            app.maxmissingSpinnerLabel.Text = 'max missing';
            app.maxmissingSpinner = uispinner(app.figure1);
            app.maxmissingSpinner.Limits = [0 40];
            app.maxmissingSpinner.ValueDisplayFormat = '%d';
            app.maxmissingSpinner.Tag = 'spinnerMaxMissing';
            app.maxmissingSpinner.Value = 2;
            app.checkboxImpute = uicheckbox(app.figure1);
            app.checkboxImpute.ValueChangedFcn = createCallbackFcn(app, @checkboxImputeValueChanged2, true);
            app.checkboxImpute.Tag = 'checkboxImpute';
            app.checkboxImpute.Text = 'Statistically impute missing values';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EegBatchExtractApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)EegBatchExtract_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end