classdef EegBatchReview_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainGUI            matlab.ui.Figure
        pushbutton2        matlab.ui.control.Button
        editSelectFileNum  matlab.ui.control.EditField
        editSelectPath     matlab.ui.control.EditField
        pushbutton1        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EegBatchReview_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.MainGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to EegBatchReview (see VARARGIN)
            
            % Choose default command line output for EegBatchReview
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes EegBatchReview wait for user response (see UIRESUME)
            % uiwait(handles.MainGUI);
        end

        % Value changed function: editSelectFileNum
        function editSelectFileNum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editSelectFileNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editSelectFileNum as text
            %        str2double(get(hObject,'String')) returns contents of editSelectFileNum as a double
        end

        % Value changed function: editSelectPath
        function editSelectPath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editSelectPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editSelectPath as text
            %        str2double(get(hObject,'String')) returns contents of editSelectPath as a double
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            FilterSpec = {'*.set', 'EEGLAB'};
            fid = fopen('.EegBatchReview_FilePath.ini','r');
            defaultpath = ".";
            if fid>0
                try
                    defaultpath = fgetl(fid);
                    fclose(fid);
                catch
                end
            end
            [filenames, pathname, ~] = uigetfile(FilterSpec, ...
                'Select an EEGLAB file', defaultpath, ...
                'multiselect', 'on');
            
            % Check if the user selected files or canceled
            if isequal(filenames, 0)
                disp('User canceled file selection.');
                return;
            end
            
            % Ensure filenames is a cell array (single selection returns a char)
            if ischar(filenames)
                filenames = {filenames};
            end
            
            % save the path when not cancelled
            fid = fopen('.EegBatchReview_FilePath.ini','w');
            if fid>0
                fprintf(fid,'%s',pathname);
                fclose(fid);
            end
            
            % save in struct and wait for next buttonpress
            data.filenames = filenames;
            data.pathname = pathname;
            
            data.editSelectPath.String = pathname;
            data.editSelectFileNum.String = sprintf('%d files', length(filenames));
            
            guidata(hObject,data)
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            % Store batch state
            data.batch.index = 1;
            data.batch.total = length(data.filenames);
            data.batch.hObject = hObject;  % for later use in callbacks
            
            guidata(hObject, data);
            
            % Start batch process
            startReviewForNextFile(hObject);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainGUI = uifigure('Visible', 'off');
            app.MainGUI.Name = 'EegBatchReview';
            app.MainGUI.Tag = 'MainGUI';
            app.pushbutton1 = uibutton(app.MainGUI, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Tooltip = 'Select EEGLAB files';
            app.pushbutton1.Text = 'Select files';
            app.editSelectPath = uieditfield(app.MainGUI, 'text');
            app.editSelectPath.ValueChangedFcn = createCallbackFcn(app, @editSelectPath_Callback, true);
            app.editSelectPath.Tag = 'editSelectPath';
            app.editSelectPath.Value = 'Edit Text';
            app.editSelectFileNum = uieditfield(app.MainGUI, 'text');
            app.editSelectFileNum.ValueChangedFcn = createCallbackFcn(app, @editSelectFileNum_Callback, true);
            app.editSelectFileNum.Tag = 'editSelectFileNum';
            app.editSelectFileNum.Value = 'Edit Text';
            app.pushbutton2 = uibutton(app.MainGUI, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Batch';
            % Show the figure after all components are created
            app.MainGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EegBatchReview_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)EegBatchReview_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainGUI)
        end
    end
end